classdef ARBUR_v1 < matlab.apps.AppBase
    %ARBUR_V1
    %   % Main script for ARBUR_v1 app
    %   %
    %   % Copyright (c) 2023 Beijing Institute of Technology (BIT), China. 
    %   % All rights reserved.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        USVimage        matlab.ui.control.Image
        ULabel          matlab.ui.control.Label
        BLabel          matlab.ui.control.Label
        Gauge           matlab.ui.control.LinearGauge
        USVs            matlab.ui.control.Image
        Behavior        matlab.ui.control.Image
        ListBox         matlab.ui.control.ListBox
        RLabel          matlab.ui.control.Label
        LLabel          matlab.ui.control.Label
        Image           matlab.ui.control.Image
        LoadDataButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'ARBUR_v1';
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            image_name = ['DemoData\3D_position\Stereo_image\',value,'.png'] ;
            image = imread(image_name);
            image_right = imcrop(image,[0,0,720,576]);
            image_left = imcrop(image,[721,0,1440,576]);

            left_point = readtable('DemoData\3D_position\Primary.csv');
            right_point = readtable('DemoData\3D_position\Secondary.csv');
            var = load('Results\demo_SSL_label.mat');
            voc_rat = var.SSL_output;
            point_row = find(strcmp(table2array(left_point(:,1)),value));

            num_rat = voc_rat(point_row);

            left_point_v = table2array(left_point(point_row,num_rat*3-1));
            left_point_u = table2array(left_point(point_row,num_rat*3));

            right_point_v = table2array(right_point(point_row,num_rat*3-1));
            right_point_u = table2array(right_point(point_row,num_rat*3));


            lumin = left_point_u-10;
            if lumin<1
                lumin = 1;
            end
            lumax = left_point_u+10;
            if lumax > 576
                lumax = 576;
            end
            lvmin = left_point_v-10;
            if lvmin<1
                lvmin = 1;
            end
            lvmax = left_point_v+10;
            if lvmax > 720
                lvmax = 720;
            end
            rumin = right_point_u-10;
            if rumin<1
                rumin = 1;
            end
            rumax = right_point_u+10;
            if rumax > 576
                rumax = 576;
            end
            rvmin = right_point_v-10;
            if rvmin<1
                rvmin = 1;
            end
            rvmax = right_point_v+10;
            if rvmax > 720
                rvmax = 720;
            end

            load('Results\demo_USV_label.mat');
            usv_type = str2num(USV_label(point_row,2));
            if usv_type <=10 && usv_type >= 1
                image_left(lumin:lumax,lvmin:lvmax,1) = 112;
                image_right(rumin:rumax,rvmin:rvmax,1) = 112;
                image_left(lumin:lumax,lvmin:lvmax,2) = 48;
                image_right(rumin:rumax,rvmin:rvmax,2) = 48;
                image_left(lumin:lumax,lvmin:lvmax,3) = 160;
                image_right(rumin:rumax,rvmin:rvmax,3) = 160;
            end
            if usv_type <=35 && usv_type >= 11
                image_left(lumin:lumax,lvmin:lvmax,1) = 199;
                image_right(rumin:rumax,rvmin:rvmax,1) = 199;
                image_left(lumin:lumax,lvmin:lvmax,2) = 161;
                image_right(rumin:rumax,rvmin:rvmax,2) = 161;
                image_left(lumin:lumax,lvmin:lvmax,3) = 227;
                image_right(rumin:rumax,rvmin:rvmax,3) = 227;
            end
            if usv_type <=45 && usv_type >= 36
                image_left(lumin:lumax,lvmin:lvmax,1) = 0;
                image_right(rumin:rumax,rvmin:rvmax,1) = 0;
                image_left(lumin:lumax,lvmin:lvmax,2) = 192;
                image_right(rumin:rumax,rvmin:rvmax,2) = 192;
                image_left(lumin:lumax,lvmin:lvmax,3) = 187;
                image_right(rumin:rumax,rvmin:rvmax,3) = 187;
            end
            if usv_type <=60 && usv_type >= 46
                image_left(lumin:lumax,lvmin:lvmax,1) = 9;
                image_right(rumin:rumax,rvmin:rvmax,1) = 9;
                image_left(lumin:lumax,lvmin:lvmax,2) = 255;
                image_right(rumin:rumax,rvmin:rvmax,2) = 255;
                image_left(lumin:lumax,lvmin:lvmax,3) = 249;
                image_right(rumin:rumax,rvmin:rvmax,3) = 249;
            end
            if usv_type <=65 && usv_type >= 61
                image_left(lumin:lumax,lvmin:lvmax,1) = 255;
                image_right(rumin:rumax,rvmin:rvmax,1) = 255;
                image_left(lumin:lumax,lvmin:lvmax,2) = 0;
                image_right(rumin:rumax,rvmin:rvmax,2) = 0;
                image_left(lumin:lumax,lvmin:lvmax,3) = 0;
                image_right(rumin:rumax,rvmin:rvmax,3) = 0;
            end


            app.Image.ImageSource = [image_left,image_right];
            selectedIndex = find(strcmp(app.ListBox.Items, value));
            app.Gauge.Value = selectedIndex;

            sp_name = split(value,'_');
            usv_name = ['Results\USV\',str2mat(sp_name(1)),'_',str2mat(sp_name(2)),'_',str2mat(sp_name(3)),'_',str2mat(sp_name(4)),'_usv.jpg'] ;
            imageusv = imread(usv_name);
            app.USVimage.ImageSource = imageusv;

        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            var = load('Results\demo_behavior_label.mat');
            variables = var.SB_label;
            for f = 1:size(variables,1)
                frame_name(f,1) = variables{f,1};
            end
            app.ListBox.Items = frame_name;

            B_seq = zeros(1,size(variables,1));
            for f = 1:size(variables,1)
                if strcmp('SO(solitary)',variables{f,2})
                    B_seq(f) = 1;
                end
                if strcmp('SL(sleep)',variables{f,2})
                    B_seq(f) = 2;
                end
                if strcmp('PIN(pining)',variables{f,2})
                    B_seq(f) = 3;
                end
                if strcmp('POU(poucing)',variables{f,2})
                    B_seq(f) = 4;
                end
                if strcmp('SNC(social nose contact)',variables{f,2})
                    B_seq(f) = 5;
                end
                if strcmp('MA(moving away)',variables{f,2})
                    B_seq(f) = 6;
                end
                if strcmp('AP(approaching)',variables{f,2})
                    B_seq(f) = 7;
                end
                if strcmp('FO(following)',variables{f,2})
                    B_seq(f) = 8;
                end
            end

            U_seq = zeros(1,size(variables,1));
            load('Results\demo_USV_label.mat');
            for i = 1:size(USV_label,1)
                usv_type = str2num(USV_label(i,2));
                if usv_type <=10 && usv_type >= 1
                    Ethogram_USVs(1,i) = 1;
                end
                if usv_type <=35 && usv_type >= 11
                    Ethogram_USVs(1,i) = 2;
                end
                if usv_type <=45 && usv_type >= 36
                    Ethogram_USVs(1,i) = 3;
                end
                if usv_type <=60 && usv_type >= 46
                    Ethogram_USVs(1,i) = 4;
                end
                if usv_type <=65 && usv_type >= 61
                    Ethogram_USVs(1,i) = 5;
                end
            end

            BMap = [
                0, 0, 0;   % 0
                0, 176, 240;   % 1
                0, 112, 192;    % 2
                249, 250, 20    % 3
                252,185,10;   % 4
                255,127,0    % 5
                146,255,97    % 6
                128,213,84    % 7
                112,173,71   % 8


                ]/255;
            UMap = [
                0, 0, 0;   % 0
                112, 48, 160;   % 1
                199, 161, 227;    % 2
                0, 192, 187    % 3
                9,255,249;   % 4
                255,0,0    % 5
                ]/255;
            gcb = figure('visible','off');
            imagesc(B_seq,[0,8]);
            set(gcb,'position',[100,100,1010,50])
            set(gca,'Position',[0 0 1 1]);
            colormap(BMap);
            axis off;
            saveas(gcb,'Results\Ethogram\behavior','png')

            gcu = figure('visible','off');
            imagesc(Ethogram_USVs,[0,5]);
            set(gcu,'position',[100,100,1010,50])
            set(gca,'Position',[0 0 1 1]);
            colormap(UMap);
            axis off;
            saveas(gcu,'Results\Ethogram\USV','png')         
            
            behavior_eth = imread('Results\Ethogram\behavior.png');
            app.Behavior.ImageSource = behavior_eth;

            usv_eth = imread('Results\Ethogram\USV.png');
            app.USVs.ImageSource = usv_eth;

            app.Gauge.Limits = [1,size(variables,1)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Tag = 'loadVariablesButton';
            app.LoadDataButton.Text = 'Load  Data';
            app.Image = uiimage(app.UIFigure);
            app.Image.Tag = 'imageleft';
            app.Image.ImageSource = 'ARBUR_Icon.png';
            app.LLabel = uilabel(app.UIFigure);
            app.LLabel.Text = 'L';
            app.RLabel = uilabel(app.UIFigure);
            app.RLabel.Text = 'R';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'', '', '', ''};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Tag = 'variableListbox';
            app.ListBox.Value = '';
            app.Behavior = uiimage(app.UIFigure);
            app.Behavior.ImageSource = 'ARBUR_Icon.png';
            app.USVs = uiimage(app.UIFigure);
            app.USVs.ImageSource = 'ARBUR_Icon.png';
            app.Gauge = uigauge(app.UIFigure, 'linear');
            app.BLabel = uilabel(app.UIFigure);
            app.BLabel.Text = 'B:';
            app.ULabel = uilabel(app.UIFigure);
            app.ULabel.Text = 'U:';
            app.USVimage = uiimage(app.UIFigure);
            app.USVimage.ImageSource = 'ARBUR_Icon.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ARBUR_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end